classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveinFolderButton            matlab.ui.control.Button
        TheimageafterprocessingLabel  matlab.ui.control.Label
        FeedBackButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Image                         matlab.ui.control.Image
        DropDownLabel                 matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Tab2                          matlab.ui.container.Tab
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditField2Label               matlab.ui.control.Label
        EditField2                    matlab.ui.control.NumericEditField
        UIAxes2                       matlab.ui.control.UIAxes
        BrowseButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [filename,filepath] =uigetfile({'*.*;*.jpg;*.png;*,bmp;*.oct'},'Select File to Open');
           fullname = [filepath,filename];
           imagefile = imread(fullname);
           imshow(imagefile,'parent',app.UIAxes);
           imshow(imagefile,'parent',app.UIAxes2);
        end

        % Button pushed function: SaveinFolderButton
        function SaveinFolderButtonPushed(app, event)
            if strcmp(app.DropDown.Value,app.DropDown.Items{4})
                app.UIAxes2.Position(3:4) = [app.EditField.Value app.EditField2.Value];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveinFolderButton = uibutton(app.UIFigure, 'push');
            app.SaveinFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinFolderButtonPushed, true);
            app.SaveinFolderButton.Text = 'Save in Folder';
            app.TheimageafterprocessingLabel = uilabel(app.UIFigure);
            app.TheimageafterprocessingLabel.Text = 'The image after processing';
            app.FeedBackButton = uibutton(app.UIFigure, 'push');
            app.FeedBackButton.Text = 'FeedBack?';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.EditFieldLabel = uilabel(app.Tab2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab2, 'numeric');
            app.EditField2Label = uilabel(app.Tab2);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.Tab2, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end